classdef MOONLANDER < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        Image                      matlab.ui.control.Image
        STARTButton                matlab.ui.control.Button
        STOPButton                 matlab.ui.control.Button
        THRUSTButton               matlab.ui.control.Button
        CWButton                   matlab.ui.control.Button
        CCWButton                  matlab.ui.control.Button
        TraceCheckBox              matlab.ui.control.CheckBox
        musicCheckBox              matlab.ui.control.CheckBox
        XvelocityGaugeLabel        matlab.ui.control.Label
        XvelocityGauge             matlab.ui.control.SemicircularGauge
        FUELGaugeLabel             matlab.ui.control.Label
        FUELGauge                  matlab.ui.control.LinearGauge
        YvelocityGaugeLabel        matlab.ui.control.Label
        YvelocityGauge             matlab.ui.control.SemicircularGauge
        Label                      matlab.ui.control.Label
        emredemirlimetuedutrLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTButton
        function STARTButtonPushed(app, event)
        persistent once 
        persistent frame_buffer
        global player playerex playeryea fuel
        global t_g acc_counter th onsce trace
        
        if isempty(once)
            frame_buffer = zeros(480,640,'gpuArray');
            [Y,Fs] = audioread('gsg.mp3');
            player = audioplayer(Y,Fs);
            [Y,Fs] = audioread('ex.mp3');
            playerex = audioplayer(Y,Fs);
            [Y,Fs] = audioread('yeah.mp3');
            playeryea = audioplayer(Y,Fs);
            once = 0;
            trace = 0;
        end
        play(player);
        
        app.STARTButton.BackgroundColor = [0.8922 0.8314 0.1745];
        app.STARTButton.Text = 'BE CAREFUL';
        fuel = 60;
        xv = 10;        
        yv = 0;
        Dt = 0.01;
        stop = 1;

 
        g = 4.8;
        %x,y
        p0 = gpuArray([16 1]);
        p1 = gpuArray([1 10]);
        p2 = gpuArray([16 20]);
        p3 = gpuArray([33 10]);
        p4 = gpuArray([1 30]);
        p5 = gpuArray([16 30]);
        p6 = gpuArray([33 30]);
        
        th = 0;
        th_total = 0;
        onsce = 0;
        
        t_total = 0;
        acc_counter = 0;
        
        while(stop)
            tic
            if(t_total >= 0.05)
                if(~trace)
                    frame_buffer = frame_buffer * 0;
                end
%               
                frame_buffer = DrawLine([1,250],[440, 450], frame_buffer);
                frame_buffer = DrawLine([440,450],[520, 450], frame_buffer);
                frame_buffer = DrawLine([520,450],[620, 30], frame_buffer);
                frame_buffer = DrawLine(double(p0_int),double(p1_int),frame_buffer);
                frame_buffer = DrawLine(double(p1_int),double(p2_int),frame_buffer);
                frame_buffer = DrawLine(double(p2_int),double(p3_int),frame_buffer);
                frame_buffer = DrawLine(double(p3_int),double(p0_int),frame_buffer);
                frame_buffer = DrawLine(double(p1_int),double(p4_int),frame_buffer);
                frame_buffer = DrawLine(double(p3_int),double(p6_int),frame_buffer);
                if acc_counter ~=0
                    frame_buffer = DrawLine(double(p2_int),double(p5_int),frame_buffer);
                end
                t_total = 0;
                app.Image.ImageSource = repmat(frame_buffer, 1, 1, 3);
                drawnow;
%               app.STOPButton.Text = num2str(fuel);
                app.XvelocityGauge.Value = xv;
                app.YvelocityGauge.Value = yv;
%               app.FUELGauge.Value(11);
                app.FUELGauge.Value = fuel;
            else
                
            end
            
            if acc_counter > 0 && acc_counter < 350
                thr = -2*g;
                acc_counter = acc_counter - 2;
            else
                acc_counter = 0;
                thr = 0;
            end
            
            ax = -thr*sind(th_total);
            ay = g+thr*cosd(th_total);
            
            xv = xv + ax*Dt;
            yv = yv + ay*Dt;    
            
            p0(1,1) = p0(1,1) + xv*Dt;
            p0(1,2) = p0(1,2) + yv*Dt;
            
            p1(1,1) = p1(1,1) + xv*Dt;
            p1(1,2) = p1(1,2) + yv*Dt;
            
            p2(1,1) = p2(1,1) + xv*Dt;
            p2(1,2) = p2(1,2) + yv*Dt;
            
            p3(1,1) = p3(1,1) + xv*Dt;
            p3(1,2) = p3(1,2) + yv*Dt;
            
            p4(1,1) = p4(1,1) + xv*Dt;
            p4(1,2) = p4(1,2) + yv*Dt;
            
            p5(1,1) = p5(1,1) + xv*Dt;
            p5(1,2) = p5(1,2) + yv*Dt;
            
            p6(1,1) = p6(1,1) + xv*Dt;
            p6(1,2) = p6(1,2) + yv*Dt;
            
            
            if onsce
                th_total = th_total + th;
                if(th_total == 95)
                    th = 0;
                    th_total = 90;
                elseif(th_total == -95)
                    th_total = -90;
                    th = 0;
                else
                    Dx = (p1(1,1)-p0(1,1));
                    Dy = (p1(1,2)-p0(1,2));
                    p1(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p1(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p1(1,1) = p1(1,1)+p0(1,1);
                    p1(1,2) = p1(1,2)+p0(1,2);
                    
                    Dx = (p2(1,1)-p0(1,1));
                    Dy = (p2(1,2)-p0(1,2));
                    p2(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p2(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p2(1,1) = p2(1,1)+p0(1,1);
                    p2(1,2) = p2(1,2)+p0(1,2);
                    
                    Dx = (p3(1,1)-p0(1,1));
                    Dy = (p3(1,2)-p0(1,2));
                    p3(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p3(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p3(1,1) = p3(1,1)+p0(1,1);
                    p3(1,2) = p3(1,2)+p0(1,2);
                    
                    Dx = (p4(1,1)-p0(1,1));
                    Dy = (p4(1,2)-p0(1,2));
                    p4(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p4(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p4(1,1) = p4(1,1)+p0(1,1);
                    p4(1,2) = p4(1,2)+p0(1,2);
                    
                    Dx = (p5(1,1)-p0(1,1));
                    Dy = (p5(1,2)-p0(1,2));
                    p5(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p5(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p5(1,1) = p5(1,1)+p0(1,1);
                    p5(1,2) = p5(1,2)+p0(1,2);
                    
                    Dx = (p6(1,1)-p0(1,1));
                    Dy = (p6(1,2)-p0(1,2));
                    p6(1,1) = Dx*cosd(th)-Dy*sind(th);
                    p6(1,2) = Dy*cosd(th)+Dx*sind(th);
                    p6(1,1) = p6(1,1)+p0(1,1);
                    p6(1,2) = p6(1,2)+p0(1,2);
                    onsce = 0;
                    th = 0;
                end

            end
            p0_int = [uint16(p0(1,1)) uint16(p0(1,2))];
            p1_int = [uint16(p1(1,1)) uint16(p1(1,2))];
            p2_int = [uint16(p2(1,1)) uint16(p2(1,2))];
            p3_int = [uint16(p3(1,1)) uint16(p3(1,2))];
            p4_int = [uint16(p4(1,1)) uint16(p4(1,2))];
            p5_int = [int16(p5(1,1)) int16(p5(1,2))];
            p6_int = [uint16(p6(1,1)) uint16(p6(1,2))];
            
            if (p5_int(1,1) > 640 || p5_int(1,2) > 480 ||...
                    p5_int(1,1) < 1 || p5_int(1,2) < 1)
                pause(player);
                play(playerex);
                pause(1.2);
                pause(playerex);
                app.STARTButton.BackgroundColor = [0.3922 0.8314 0.0745];
                app.STARTButton.Text = 'PLAY AGAIN';
                break;
            end

            frame_buffer = DrawLine([1,250],[440, 450], frame_buffer);
            frame_buffer = DrawLine([440,450],[520, 450], frame_buffer);
            frame_buffer = DrawLine([520,450],[620, 30], frame_buffer);
            if(((p5_int(1,1)*0.455+249.5-p5_int(1,2))<0) || ...
                    ((p5_int(1,1)*(-4.2)+2634-p5_int(1,2))<0) || ...
                    (p5_int(1,2)>450))
                if((p5_int(1,2)>450) && yv<10 && xv <10)
                    app.STOPButton.Text = 'YEYY';
                    pause(player);
                    play(playeryea);
                else
                    app.STOPButton.Text = 'NOPE';
                    pause(player);
                    play(playerex);
                end
                pause(1.2);
                pause(playerex);
                app.STARTButton.BackgroundColor = [0.3922 0.8314 0.0745];
                app.STARTButton.Text = 'PLAY AGAIN';
                break;
            end
            Dt = toc;
            t_g = Dt;
            t_total = t_total + Dt;
        end


        end

        % Button pushed function: THRUSTButton
        function THRUSTButtonPushed(app, event)
            global acc_counter t_g fuel

            if( fuel ~= 0)
                fuel = fuel - 1;
                acc_counter = acc_counter + t_g*50;
            else
                acc_counter = 0;
            end
        end

        % Button pushed function: CWButton
        function CWButtonPushed(app, event)
            global  th onsce
            th = 5;
            onsce = 1;
        end

        % Button pushed function: CCWButton
        function CCWButtonPushed(app, event)
            global  th onsce
            th =  -5;
            onsce = 1;
        end

        % Value changed function: TraceCheckBox
        function TraceCheckBoxValueChanged(app, event)
            global trace
            value = app.TraceCheckBox.Value;
            trace = value;
        end

        % Value changed function: musicCheckBox
        function musicCheckBoxValueChanged(app, event)
          global player
            value = app.musicCheckBox.Value;

            if value
                resume(player);
            else
                pause(player);
            end
            
        end

        % Button pushed function: STOPButton
        function STOPButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.STARTButton = uibutton(app.UIFigure, 'push');
            app.STARTButton.ButtonPushedFcn = createCallbackFcn(app, @STARTButtonPushed, true);
            app.STARTButton.Text = 'START';
            app.STOPButton = uibutton(app.UIFigure, 'push');
            app.STOPButton.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton.Text = 'STOP';
            app.THRUSTButton = uibutton(app.UIFigure, 'push');
            app.THRUSTButton.ButtonPushedFcn = createCallbackFcn(app, @THRUSTButtonPushed, true);
            app.THRUSTButton.Text = 'THRUST';
            app.CWButton = uibutton(app.UIFigure, 'push');
            app.CWButton.ButtonPushedFcn = createCallbackFcn(app, @CWButtonPushed, true);
            app.CWButton.Text = 'CW';
            app.CCWButton = uibutton(app.UIFigure, 'push');
            app.CCWButton.ButtonPushedFcn = createCallbackFcn(app, @CCWButtonPushed, true);
            app.CCWButton.Text = 'CCW';
            app.TraceCheckBox = uicheckbox(app.UIFigure);
            app.TraceCheckBox.ValueChangedFcn = createCallbackFcn(app, @TraceCheckBoxValueChanged, true);
            app.TraceCheckBox.Text = 'Trace';
            app.musicCheckBox = uicheckbox(app.UIFigure);
            app.musicCheckBox.ValueChangedFcn = createCallbackFcn(app, @musicCheckBoxValueChanged, true);
            app.musicCheckBox.Text = 'music';
            app.musicCheckBox.Value = true;
            app.XvelocityGaugeLabel = uilabel(app.UIFigure);
            app.XvelocityGaugeLabel.Text = 'X velocity';
            app.XvelocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.XvelocityGauge.Limits = [0 50];
            app.FUELGaugeLabel = uilabel(app.UIFigure);
            app.FUELGaugeLabel.Text = 'FUEL';
            app.FUELGauge = uigauge(app.UIFigure, 'linear');
            app.FUELGauge.Limits = [0 60];
            app.FUELGauge.Value = 11;
            app.YvelocityGaugeLabel = uilabel(app.UIFigure);
            app.YvelocityGaugeLabel.Text = 'Y velocity';
            app.YvelocityGauge = uigauge(app.UIFigure, 'semicircular');
            app.YvelocityGauge.Limits = [0 50];
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Land onto the flat line. Your final x and y speed must be smaller than 10.';
            app.emredemirlimetuedutrLabel = uilabel(app.UIFigure);
            app.emredemirlimetuedutrLabel.Text = 'emre.demirli@metu.edu.tr';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = MOONLANDER

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end